classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Image     matlab.ui.control.Image
        Button    matlab.ui.control.Button
        Button_2  matlab.ui.control.Button
        Button_3  matlab.ui.control.Button
        Button_4  matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
[filename, pathname] = uigetfile({'*.jpg;*.png', 'Image Files'}, 'Select an image file');
            if isequal(filename, 0)
                return;
            end
            fullFileName = fullfile(pathname, filename);
            imageData = imread(fullFileName);
            app.Image.ImageSource = imageData;

        end

        % Image clicked function: Image
        function ImageClicked(app, event)
         
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
     if isempty(app.Image.ImageSource)
                msgbox('Please load an image first.', 'Error', 'error');
                return;
            end
            dilatedImage = imdilate(app.Image.ImageSource, strel('disk',1));
    app.Image.ImageSource = dilatedImage;
           
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            if isempty(app.Image.ImageSource)
                msgbox('Please load an image first.', 'Error', 'error');
                return;
            end
            erodedImage = imerode(app.Image.ImageSource, strel('disk',1));
    app.Image.ImageSource = erodedImage;
        end

        % Callback function
        function Button_4ValueChanged(app, event)
    
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            if isempty(app.Image.ImageSource)
        msgbox('Please load an image first.', 'Error', 'error');
        return;
    end
    edgesR = edge(app.Image.ImageSource(:,:,1), 'Sobel');
    edgesG = edge(app.Image.ImageSource(:,:,2), 'Sobel');
    edgesB = edge(app.Image.ImageSource(:,:,3), 'Sobel');
    
    edgesImage = cat(3, edgesR, edgesG, edgesB);
    edgesImage = im2uint8(edgesImage);
    
    app.Image.ImageSource = edgesImage;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Загрузите изображение';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = 'Утоньшение границ';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = 'Утолщение границ';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = 'Найти границы изображения';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end